classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FitDataButton                   matlab.ui.control.Button
        Mu1EditFieldLabel               matlab.ui.control.Label
        Mu1EditField                    matlab.ui.control.NumericEditField
        Mu2EditFieldLabel               matlab.ui.control.Label
        Mu2EditField                    matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        Sigma1EditFieldLabel            matlab.ui.control.Label
        Sigma1EditField                 matlab.ui.control.NumericEditField
        Sigma2EditFieldLabel            matlab.ui.control.Label
        Sigma2EditField                 matlab.ui.control.NumericEditField
        MixEditFieldLabel               matlab.ui.control.Label
        MixEditField                    matlab.ui.control.NumericEditField
        IterationEditFieldLabel         matlab.ui.control.Label
        IterationEditField              matlab.ui.control.NumericEditField
        EstimatedMixEditFieldLabel      matlab.ui.control.Label
        EstimatedMixEditField           matlab.ui.control.NumericEditField
        PDFgainSliderLabel              matlab.ui.control.Label
        PDFgainSlider                   matlab.ui.control.Slider
        UITable                         matlab.ui.control.Table
        GeneratedMu1EditFieldLabel      matlab.ui.control.Label
        GeneratedMu1EditField           matlab.ui.control.NumericEditField
        GeneratedMu2EditFieldLabel      matlab.ui.control.Label
        GeneratedMu2EditField           matlab.ui.control.NumericEditField
        GSigma1EditFieldLabel           matlab.ui.control.Label
        GSigma1EditField                matlab.ui.control.NumericEditField
        GSigma2EditFieldLabel           matlab.ui.control.Label
        GSigma2EditField                matlab.ui.control.NumericEditField
        NumberofSamplesofEachEditFieldLabel  matlab.ui.control.Label
        NumberofSamplesofEachEditField  matlab.ui.control.NumericEditField
        GenerateButton                  matlab.ui.control.Button
        EditDataButton                  matlab.ui.control.StateButton
        IterateButton                   matlab.ui.control.Button
        ResetIterationButton            matlab.ui.control.Button
        CountEditFieldLabel             matlab.ui.control.Label
        CountEditField                  matlab.ui.control.NumericEditField
        EstimatedMu1EditFieldLabel      matlab.ui.control.Label
        EstimatedMu1EditField           matlab.ui.control.NumericEditField
        EstimatedMu2EditFieldLabel      matlab.ui.control.Label
        EstimatedMu2EditField           matlab.ui.control.NumericEditField
        EstSigma1EditFieldLabel         matlab.ui.control.Label
        EstSigma1EditField              matlab.ui.control.NumericEditField
        EstSigma2EditFieldLabel         matlab.ui.control.Label
        EstSigma2EditField              matlab.ui.control.NumericEditField
        ImportDataButton                matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
        data_used
        count = 0; % Description
    end

    properties (Access = private)
    end

    methods (Access = public)
    
        function results = func(app,a,b)
            results = a*b;
        end
        
    end

    methods (Access = private)
    
        function results = func2(app,a,b)
            results = a/b;
        end
        
        function results = gmm_calc(app, Mu1, Mu2, sigma1, sigma2, mix, num_steps, x, y)
            %initialize parameters
            Mu = [Mu1 Mu2];
            sigma = [sigma1 sigma2];
            pi = mix;
            wp1 = zeros(1, length(y)) ;
            wp2 = zeros(1, length(y)) ;
            for iteration=1:num_steps
                %Expectation Step
                %for each of the point, wich Gaussian generated it

                wp1 = pdf('Normal',y,Mu(1),sigma(1))*pi;
                wp2 = pdf('Normal',y,Mu(2),sigma(2))*(1-pi);
                dem = wp1+wp2;
                wp1 = wp1./dem;
                wp2 = wp2./dem;
                    
                %Maximization Step
                %Modify the hidden value such that is maximizes the probability
                %calc new Mu(1) 
                Mu(1)=sum(wp1.*y)/sum(wp1);
                %calc new Mu(2)
                Mu(2)=sum(wp2.*y)/sum(wp2);
                %calc new sigma(1)
                sigma(1)=sqrt(sum(wp1.*(y-Mu(1)).^2)/sum(wp1));
                %calc new sigma(2)
                sigma(2)=sqrt(sum(wp2.*(y-Mu(2)).^2)/sum(wp2));
                %calc new pi:
                pi=sum(wp2)/length(y);
                %Is Converged
            
            end
            results = [Mu(1) Mu(2) sigma(1) sigma(2) pi];    
        end
        
    end


    methods (Access = private)

        % Callback function: FitDataButton, PDFgainSlider
        function ButtonPushed(app, event)
            app.UIAxes.cla;
            y = transpose(app.UITable.Data);
            if isempty(y)
                f = warndlg('Please either enter data or generate data before esitmating a GMM','Warning');
            else    
                x = 1:max(app.data_used); %need to automate
                app.UITable.Data = transpose(y);
                gain_pdf = app.PDFgainSlider.Value;
                ret_gmm = gmm_calc(app, app.Mu1EditField.Value,app.Mu2EditField.Value,app.Sigma1EditField.Value,app.Sigma2EditField.Value,app.MixEditField.Value,app.IterationEditField.Value,x,y);
                
                hold(app.UIAxes);
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,ret_gmm(1),ret_gmm(3)), '-r', 'LineWidth',2);
                histogram(app.UIAxes, y,min(y):1:max(y));
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,ret_gmm(2),ret_gmm(4)), '-g', 'LineWidth',2)
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.Mu1EditField.Value,app.Sigma1EditField.Value), '--r', 'LineWidth',2)
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.Mu2EditField.Value,app.Sigma2EditField.Value), '--g', 'LineWidth',2)
                hold(app.UIAxes);
                app.EstimatedMu1EditField.Value = ret_gmm(1);
                app.EstimatedMu2EditField.Value = ret_gmm(2);
                app.EstSigma1EditField.Value = ret_gmm(3);
                app.EstSigma2EditField.Value = ret_gmm(4);
                app.EstimatedMixEditField.Value = ret_gmm(5);
                
            end     
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.UIAxes.cla;
            y_1 = normrnd(app.GeneratedMu1EditField.Value,app.GSigma1EditField.Value,[1,app.NumberofSamplesofEachEditField.Value]);
            y_2 = normrnd(app.GeneratedMu2EditField.Value,app.GSigma2EditField.Value,[1,app.NumberofSamplesofEachEditField.Value]);
            app.data_used = [y_1,y_2];
            x = 1:max(app.data_used); %need to automate
            app.UITable.Data = transpose(app.data_used);
            gain_pdf = 1
            hold(app.UIAxes);
            plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.GeneratedMu1EditField.Value,app.GSigma1EditField.Value), '--r', 'LineWidth',2)
            plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.GeneratedMu2EditField.Value,app.GSigma2EditField.Value), '--g', 'LineWidth',2)
            hold(app.UIAxes);
        end

        % Value changed function: EditDataButton
        function EditDataButtonValueChanged(app, event)
            value = app.EditDataButton.Value;
            app.UITable.ColumnEditable = value;
        end

        % Button pushed function: ResetIterationButton
        function ResetIterationButtonPushed(app, event)
            app.count = 0;
            app.UIAxes.cla;
            y = transpose(app.UITable.Data);
            if isempty(y)
                f = warndlg('Please either enter data or generate data before esitmating a GMM','Warning');
            else    
                x = 1:max(app.data_used); %need to automate
                app.UITable.Data = transpose(y);
                gain_pdf = app.PDFgainSlider.Value;
                ret_gmm = gmm_calc(app, app.Mu1EditField.Value,app.Mu2EditField.Value,app.Sigma1EditField.Value,app.Sigma2EditField.Value,app.MixEditField.Value,app.count,x,y);
            
                hold(app.UIAxes);
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,ret_gmm(1),ret_gmm(3)), '-r', 'LineWidth',2);
                histogram(app.UIAxes, y,min(y):1:max(y));
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,ret_gmm(2),ret_gmm(4)), '-g', 'LineWidth',2)
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.Mu1EditField.Value,app.Sigma1EditField.Value), '--r', 'LineWidth',2)
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.Mu2EditField.Value,app.Sigma2EditField.Value), '--g', 'LineWidth',2)
                hold(app.UIAxes);
                app.EstimatedMu1EditField.Value = ret_gmm(1);
                app.EstimatedMu2EditField.Value = ret_gmm(2);
                app.EstSigma1EditField.Value = ret_gmm(3);
                app.EstSigma2EditField.Value = ret_gmm(4);
                app.EstimatedMixEditField.Value = ret_gmm(5);
            end
            app.CountEditField.Value = app.count;
        end

        % Button pushed function: IterateButton
        function IterateButtonPushed(app, event)
            app.UIAxes.cla;
            y = transpose(app.UITable.Data);
            if isempty(y)
                f = warndlg('Please either enter data or generate data before esitmating a GMM','Warning');
            else    
                x = 1:max(app.data_used); %need to automate
                app.UITable.Data = transpose(y);
                gain_pdf = app.PDFgainSlider.Value;
                ret_gmm = gmm_calc(app, app.Mu1EditField.Value,app.Mu2EditField.Value,app.Sigma1EditField.Value,app.Sigma2EditField.Value,app.MixEditField.Value,app.count,x,y);
            
                hold(app.UIAxes);
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,ret_gmm(1),ret_gmm(3)), '-r', 'LineWidth',2);
                histogram(app.UIAxes, y,min(y):1:max(y));
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,ret_gmm(2),ret_gmm(4)), '-g', 'LineWidth',2)
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.Mu1EditField.Value,app.Sigma1EditField.Value), '--r', 'LineWidth',2)
                plot(app.UIAxes, gain_pdf*pdf('Normal',x,app.Mu2EditField.Value,app.Sigma2EditField.Value), '--g', 'LineWidth',2)
                hold(app.UIAxes);
                app.EstimatedMu1EditField.Value = ret_gmm(1);
                app.EstimatedMu2EditField.Value = ret_gmm(2);
                app.EstSigma1EditField.Value = ret_gmm(3);
                app.EstSigma2EditField.Value = ret_gmm(4);
                app.EstimatedMixEditField.Value = ret_gmm(5);
            end 
            app.count = app.count + 1;
            app.CountEditField.Value = app.count;
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [file] = uigetfile('*.csv');
            inputfile = csvread(file);
            app.UITable.Data = inputfile(:,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.FitDataButton = uibutton(app.UIFigure, 'push');
            app.FitDataButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.FitDataButton.Text = 'Fit Data';
            app.Mu1EditFieldLabel = uilabel(app.UIFigure);
            app.Mu1EditFieldLabel.Text = 'Mu 1';
            app.Mu1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Mu1EditField.Value = 5;
            app.Mu2EditFieldLabel = uilabel(app.UIFigure);
            app.Mu2EditFieldLabel.Text = 'Mu 2';
            app.Mu2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Mu2EditField.Value = 15;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GMM Model');
            xlabel(app.UIAxes, 'X-Axis');
            ylabel(app.UIAxes, 'Y-Axis');
            app.Sigma1EditFieldLabel = uilabel(app.UIFigure);
            app.Sigma1EditFieldLabel.Text = 'Sigma 1';
            app.Sigma1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Sigma1EditField.Value = 5;
            app.Sigma2EditFieldLabel = uilabel(app.UIFigure);
            app.Sigma2EditFieldLabel.Text = 'Sigma 2';
            app.Sigma2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Sigma2EditField.Value = 5;
            app.MixEditFieldLabel = uilabel(app.UIFigure);
            app.MixEditFieldLabel.Text = 'Mix';
            app.MixEditField = uieditfield(app.UIFigure, 'numeric');
            app.MixEditField.Limits = [0 1];
            app.MixEditField.Value = 0.2;
            app.IterationEditFieldLabel = uilabel(app.UIFigure);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationEditField.Limits = [0 Inf];
            app.IterationEditField.Value = 10;
            app.EstimatedMixEditFieldLabel = uilabel(app.UIFigure);
            app.EstimatedMixEditFieldLabel.Text = 'Estimated Mix';
            app.EstimatedMixEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstimatedMixEditField.Editable = 'off';
            app.PDFgainSliderLabel = uilabel(app.UIFigure);
            app.PDFgainSliderLabel.Text = 'PDF gain';
            app.PDFgainSlider = uislider(app.UIFigure);
            app.PDFgainSlider.Limits = [1 100];
            app.PDFgainSlider.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PDFgainSlider.MinorTicks = [];
            app.PDFgainSlider.Value = 5;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Data Created'};
            app.UITable.RowName = {};
            app.GeneratedMu1EditFieldLabel = uilabel(app.UIFigure);
            app.GeneratedMu1EditFieldLabel.Text = 'Generated Mu 1';
            app.GeneratedMu1EditField = uieditfield(app.UIFigure, 'numeric');
            app.GeneratedMu1EditField.Value = 22;
            app.GeneratedMu2EditFieldLabel = uilabel(app.UIFigure);
            app.GeneratedMu2EditFieldLabel.Text = 'Generated Mu 2';
            app.GeneratedMu2EditField = uieditfield(app.UIFigure, 'numeric');
            app.GeneratedMu2EditField.Value = 12;
            app.GSigma1EditFieldLabel = uilabel(app.UIFigure);
            app.GSigma1EditFieldLabel.Text = 'G Sigma 1';
            app.GSigma1EditField = uieditfield(app.UIFigure, 'numeric');
            app.GSigma1EditField.Value = 2;
            app.GSigma2EditFieldLabel = uilabel(app.UIFigure);
            app.GSigma2EditFieldLabel.Text = 'G Sigma 2';
            app.GSigma2EditField = uieditfield(app.UIFigure, 'numeric');
            app.GSigma2EditField.Value = 3;
            app.NumberofSamplesofEachEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSamplesofEachEditFieldLabel.Text = {'Number of Samples'; 'of Each'};
            app.NumberofSamplesofEachEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSamplesofEachEditField.Value = 50;
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = ' Generate';
            app.EditDataButton = uibutton(app.UIFigure, 'state');
            app.EditDataButton.ValueChangedFcn = createCallbackFcn(app, @EditDataButtonValueChanged, true);
            app.EditDataButton.Text = 'Edit Data';
            app.IterateButton = uibutton(app.UIFigure, 'push');
            app.IterateButton.ButtonPushedFcn = createCallbackFcn(app, @IterateButtonPushed, true);
            app.IterateButton.Text = 'Iterate';
            app.ResetIterationButton = uibutton(app.UIFigure, 'push');
            app.ResetIterationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetIterationButtonPushed, true);
            app.ResetIterationButton.Text = 'Reset Iteration';
            app.CountEditFieldLabel = uilabel(app.UIFigure);
            app.CountEditFieldLabel.Text = 'Count';
            app.CountEditField = uieditfield(app.UIFigure, 'numeric');
            app.CountEditField.Editable = 'off';
            app.EstimatedMu1EditFieldLabel = uilabel(app.UIFigure);
            app.EstimatedMu1EditFieldLabel.Text = 'Estimated Mu 1';
            app.EstimatedMu1EditField = uieditfield(app.UIFigure, 'numeric');
            app.EstimatedMu1EditField.Editable = 'off';
            app.EstimatedMu2EditFieldLabel = uilabel(app.UIFigure);
            app.EstimatedMu2EditFieldLabel.Text = 'Estimated Mu 2';
            app.EstimatedMu2EditField = uieditfield(app.UIFigure, 'numeric');
            app.EstimatedMu2EditField.Editable = 'off';
            app.EstSigma1EditFieldLabel = uilabel(app.UIFigure);
            app.EstSigma1EditFieldLabel.Text = 'Est Sigma 1';
            app.EstSigma1EditField = uieditfield(app.UIFigure, 'numeric');
            app.EstSigma1EditField.Editable = 'off';
            app.EstSigma2EditFieldLabel = uilabel(app.UIFigure);
            app.EstSigma2EditFieldLabel.Text = 'Est Sigma 2';
            app.EstSigma2EditField = uieditfield(app.UIFigure, 'numeric');
            app.EstSigma2EditField.Editable = 'off';
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end